classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BuildingDropDown              matlab.ui.control.DropDown
        UpdateButton                  matlab.ui.control.Button
        BatterySimulationSwitch       matlab.ui.control.Switch
        BatterySimulationSwitchLabel  matlab.ui.control.Label
        DayofMonthSlider              matlab.ui.control.Slider
        DayofMonthSliderLabel         matlab.ui.control.Label
        BatterykWListBox              matlab.ui.control.ListBox
        BatterykWListBoxLabel         matlab.ui.control.Label
        MonthListBox                  matlab.ui.control.ListBox
        MonthListBoxLabel             matlab.ui.control.Label
        fyDropDown                    matlab.ui.control.DropDown
        fyDropDownLabel               matlab.ui.control.Label
        CorrectButton                 matlab.ui.control.Button
        DirectoryLinkTextArea         matlab.ui.control.TextArea
        DirectoryLinkLabel            matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    %% https://www.mathworks.com/help/matlab/matlab_prog/subscript-into-times-of-timetable.html
    properties (Access = public)
        directoryLink; % G:\Shared drives\Campus Energy\Staff\Claire\Resources\FilePaths.xlsx
        directory;
        
        buildingIndex;
        load;
        bldgpv;
        combinedFirst;
        toAvoidMorningPeak;
        
        d;% Description

        month; 


        fy;
        daysOfMonth;
        year;
        
        buildingName;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: DirectoryLinkTextArea, UIAxes
        function DirectoryLinkTextAreaValueChanged(app, event)
            app.directoryLink = app.DirectoryLinkTextArea.Value;
            % G:\Shared drives\Campus Energy\Staff\Claire\Resources\FilePaths.xlsx

%%%%%% MIGHT WANT TO REPLACE WITH A BUTTON WITH A CALLBACK TO THIS INSTEAD
%%%%%% OF VALUE CHANGED?  AND HAVE A BUTTON TO OUTPUT MANY FIGURES AT ONCE?
%%%%%% OR JUST A GENERATE REPORT BUTTON?

        end

        % Value changed function: DayofMonthSlider
        function DayofMonthSliderValueChanged(app, event)
            daySlider = app.DayofMonthSlider.Value;
            app.d = round(daySlider);

   %%%% PROBABLY DON'T NEED THIS SINCE I HAVE THE RUN BUTTON RIGHT NOW BUT
   %%%% MIGHT BE USEFUL FOR LATER IF WANT SOMETHING THAT UPDATES MORE
   %%%% SMOOTHLY (MIGHT BE SLOWING THE RUN TIME DOWN THOUGH)
        end

        % Button pushed function: CorrectButton
        function CorrectButtonPushed(app, event)
            % Master List
    app.directoryLink = app.DirectoryLinkTextArea.Value;          
    app.directoryLink = string(app.directoryLink);
    app.directory = readtable(app.directoryLink);
    app.BuildingDropDown.Items = app.directory.Name; % The number of buildings to do an analysis of (takes the number of roles)

    app.DirectoryLinkTextArea.Visible = false;
    app.DirectoryLinkLabel.Visible = false;
    app.CorrectButton.Visible = false;

    app.UpdateButton.Visible = true;
    app.fyDropDown.Visible = true;
    app.fyDropDownLabel.Visible = true;
    app.MonthListBox.Visible = true;
    app.MonthListBoxLabel.Visible = true;
    app.UIAxes.Visible = true;
    app.BuildingDropDown.Visible = true;
    app.DayofMonthSlider.Visible = true;
    app.DayofMonthSliderLabel.Visible = true;
    
    %% I'm currently getting the system sizes from Soch's Tableau
% https://public.tableau.com/app/profile/socheatha.tork/viz/PVRendition/FuturePVSystems

    % PV Data
x = "G:\Shared drives\Spring_2024_EE496\Data files\pv_lower_campus_parking_structure_phaseII_15min.csv";

pvdata = readtimetable(x); %"G:\Shared drives\Fall_2023_EE496\Data files\pv_lower_campus_parking_structure_phaseII_15min.csv");
pvdata = pvdata(:,"kw_average"); % Note: it does not look at time as one of the variables
pvdata.Properties.DimensionNames{1}='time'; % time is not a variable, this is how to rename it, mine came in as datetime, not Time like most of yours did.
pvdata = renamevars(pvdata, "kw_average", 'pvkw'); % rename a variable
app.bldgpv = pvdata(:, "pvkw"); % selects only time and kw, bpv means building pv

        end

        % Value changed function: MonthListBox
        function MonthListBoxValueChanged(app, event)
            app.fy = app.fyDropDown.Value;
            app.fy = uint32(app.fy);
            app.month = app.MonthListBox.Value;
            app.month = uint32(app.month);
            if app.month < 7    %%%%%% CHECK IF I DID FISCAL YEARS CORRECTLY!!!
                app.year = app.fy+1;
            else
                app.year = app.fy;
            end
            app.year = uint32(app.year);
            app.daysOfMonth = eomday(app.year,app.month);
            app.daysOfMonth = uint8(app.daysOfMonth);
            app.daysOfMonth = mean(app.daysOfMonth); % Don't know why need, but do.
            app.daysOfMonth = ceil(app.daysOfMonth); % round all int values up
            app.DayofMonthSlider.Limits = [1, app.daysOfMonth]; 

        end

        % Value changed function: fyDropDown
        function fyDropDownValueChanged(app, event)
            app.fy = app.fyDropDown.Value;
            app.fy = double(app.fy);
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
%%% Initialize Variables %%%
    app.BatterySimulationSwitch.Visible = true;
    app.BatterySimulationSwitchLabel.Visible = true;
% Load Building Information %
    app.buildingName = app.BuildingDropDown.Value;
    app.buildingName = string(app.buildingName);
    bldgNameIdx = app.buildingName == app.directory.Name;
    y = string(app.directory.LoadFilePath(bldgNameIdx));
    pvScaling = app.directory.PV_Scaling(bldgNameIdx);

        % Adding daytype %
%        dayType = readtimetable("G:\Shared drives\Campus Energy\Staff\timestamp_15min_daytype.csv");

    %%% START FOR LOOP FOR ALL BUILDING LOADS %%%
%%% Making Data Time Tables %%%

% Load data
loadBuilding = y;
app.load = readtimetable(loadBuilding);
app.load = app.load(:, "kw_average"); % selects columns I want
app.load.Properties.DimensionNames{1} = 'time'; % renames datetime
app.load = renamevars(app.load, "kw_average", 'loadkw'); % renames variable
app.load = sortrows(app.load,"time","ascend"); % IMPORTANT OTHERWISE DOESN'T WORK FOR ALL TABLES CAUSE OUT OF ORDER
   
% Synchronize time tables
time = (datetime(app.load.time(1)):minutes(15):datetime(app.load.time(end)))'; % Takes 15 minute intervals for a fiscal year from July 1 to July 1
time = time(1:length(time)-1,1); % 4 * 24 * 365 = 35040
TT = timetable(time,(1:length(time))'); % initializes all times (random var1)
app.combinedFirst = synchronize(TT,app.bldgpv,app.load,"first","fillwithmissing"); % bases Times off of first (TT)

% Focus Day Datetime Information %
    app.MonthListBoxValueChanged;

    daySlider = app.DayofMonthSlider.Value;
    app.d = round(daySlider);
    
    y = uint32(mean(app.year));
    m = uint32(mean(app.month));
    d = uint32(mean(app.d));
        
    startDay = datetime((y(1)),(m(1)),(d(1)),0,0,0);%(year,7,1,0,0,0);%(app.year,app.month,app.d,0,0,0);
    endDay = datetime((y(1)),(m(1)),(d(1))+1,0,0,0);%(2022,7,1,23,59,59);%(app.year,app.month,app.d,23,59,59);
    oneDayRows = isbetween(app.combinedFirst.time,startDay,endDay);
    if nnz(oneDayRows) == 0
        warndlg("No data for this day")
    else
    end
app.combinedFirst = app.combinedFirst(oneDayRows,:); 
    
    % app.bldgpv = app.bldgpv(oneDayRows,:);
    app.combinedFirst.pvkw = normalize(app.combinedFirst.pvkw, "range", [0, pvScaling]); % Takes PV_Scaling Value from the table
    app.combinedFirst.pvkwh = app.combinedFirst.pvkw/4; % change to kWh (since 15 minutes is 1/4 of an hour, divide each 15 minute value by 4)
    app.combinedFirst.loadkwh = app.combinedFirst.loadkw/4; % change to kWh (since 15 minutes is 1/4 of an hour, divide each 15 minute value by 4)
app.combinedFirst = app.combinedFirst(:,["Var1" "pvkwh" "loadkwh"]);
    app.combinedFirst.excess(app.combinedFirst.time) = app.combinedFirst.pvkwh(app.combinedFirst.time)-app.combinedFirst.loadkwh(app.combinedFirst.time);


% % % 
% % % 
% % % OptimalBattery;

%%% MILES's PLOT %%%

% to only show energy that can be stored %  %%% could replace with max
% compared to 0
for q = 1:length(app.combinedFirst.excess)    
    if app.combinedFirst.excess(q) < 0
        app.combinedFirst.excess(q) = 0;
    end
end

%%%%%% https://www.mathworks.com/matlabcentral/answers/524939-extracting-and-plotting-max-min-values-from-timeseries

app.toAvoidMorningPeak = isbetween(app.combinedFirst.time,datetime(app.year,app.month,app.d,9,0,0), datetime(app.year,app.month,app.d+1,0,0,0));

[peak, peakIdx] = max(((app.combinedFirst.loadkwh(app.toAvoidMorningPeak)))); % After 9am
peakIdx = uint32(peakIdx);
% peakIndex = app.combinedFirst.Var1(startDay);
% peakIndex = uint32(peakIndex);
peakTime = app.combinedFirst.time(peakIdx + uint32(9*4)); % idk how to preallocate datetime so leaving for now

plot(app.UIAxes,app.combinedFirst.time, ...
    [app.combinedFirst.loadkwh, app.combinedFirst.pvkwh, app.combinedFirst.excess])
        hold (app.UIAxes, "on")
        plot(app.UIAxes,app.combinedFirst.time(peakTime),peak,'o',LineWidth=1)
        legend(app.UIAxes,"load", "pv", "excess generated","load peak")
        hold (app.UIAxes, "off")


        app.BatterySimulationSwitchValueChanged;
%%% Future value function definition %%% 
% (function definitions must be at the end of the script)
% there is a matlab extension for financial calulations in which fvfix is this same function, but the intention of this code is to not need these extensions so it is defined here
function future_value = fv(rate,years,payment) %funciton will take three agruments, the escalation rate, the number of years that the investment runs for, and the yearly payment (in this case the yearly savings)
    future_value = payment*((1+rate)^(years)-1)/rate; %this formula is a general economics priciple and the same calculation used in the future value funciton in excel
end
        end

        % Value changed function: BatterySimulationSwitch
        function BatterySimulationSwitchValueChanged(app, event)
            app.BatterykWListBox.Visible = true;
            app.BatterykWListBoxLabel.Visible = true;
            battSim = app.BatterySimulationSwitch.Value;
            battParam = double(app.BatterykWListBox.Value)/4; % in kWh
           if battSim == 1

% batteryParameter = [130 165 200]; % input("Battery kW rating: ")/4;
% NRELLiBprices = [215 199 174]; % from https://atb.nrel.gov/electricity/2023/commercial_battery_storage cost benchmark
    % up from Kaili's 2021 numbers of 168 165 144 for 2 4 6 hr batteries

    %%% Initialize final table with as many rows as are buildings (number of rows = ww)
    %% Preallocate each variable
        % Preallocate - so the variable doesn't change size each time it
        % loops and saves time overall (otherwise MATLAB complains)
% BattIndex = zeros(app.buildingIndex,1); % Keeps an index for ease of future reference (in case there is a need)
% BuildingName = app.directory.Name; % Must have building names, otherwise if initializes as a double, will have problems later
% BatterykW = zeros(app.buildingIndex,1); % Battery size (max charge per hour)
% Hours = zeros(app.buildingIndex,1); % Battery hours (total hours to charge)
% battpStoredkWh = zeros(app.buildingIndex,1); % Amount of kWh stored in the battery at that time???? %%%% CHECK%%%%%
% NRELPricingPerkWh = zeros(app.buildingIndex,1); % Pricing from NREL Website for Lithium Ion Batteries
% MoneySavedAnnually = zeros(app.buildingIndex,1); % USD saved annually by battery (only profits, no negatives)
% BatteryCost = zeros(app.buildingIndex,1); % Cost of battery
% TenYearProfit = zeros(app.buildingIndex,1); % Profit of 10 years minus the cost of the battery

%% Initialize the table with all the preallocated variables above %%
% OptimalBattery = table(BattIndex,BuildingName,BatterykW,Hours,battpStoredkWh,NRELPricingPerkWh,MoneySavedAnnually,BatteryCost,TenYearProfit); 
        % Preallocates final table to have as many rows as buildings and 9 columns


ii = height(app.combinedFirst); % original index


%%%% Battery Simulation (Initialization) %%%%  Shortened Loopable Code
% Initialize zeroes
[batt2e, batt4e, batt6e, batt2p, batt4p, batt6p] = deal(zeros(ii,1));
[batt2pStoredkWh, batt4pStoredkWh, batt6pStoredkWh] = deal(0);            % for more information on deal: https://www.mathworks.com/help/matlab/ref/deal.html

% Add shortcut for excess
excess = app.combinedFirst.pvkwh - app.combinedFirst.loadkwh; % 
battUsage = app.combinedFirst.loadkwh - app.combinedFirst.pvkwh; % 

% % DO PREALLOCATION
overallIndex = 0;
% BatterykW = zeros(length(batteryParameter)*3,1);
% Hours = zeros(length(batteryParameter)*3,1);
% NRELPricingPerkWh = zeros(length(batteryParameter)*3,1);
battpStoredkWh = zeros(3,1);
% MoneySavedAnnually = zeros(length(batteryParameter)*3,1);
% BatteryCost = zeros(length(batteryParameter)*3,1);
% TenYearProfit = zeros(length(batteryParameter)*3,1);

% Battery Simulation (if-then function)
    % for n = length(batteryTable [to get # of batteries in table for the loop])
bp = battParam; % Represents the different sized batteries (different kW rating)
for bpIndex = 1:3 % Three slots for Hours (is multiplied later) 

for i = 2:ii
    if excess(i) >= bp % when implement n, can call from table
        [batt2e(i), batt2p(i)] = deal((batt2e(i-1) + bp), bp);
        [batt4e(i), batt4p(i)] = deal((batt4e(i-1) + bp), bp);
        [batt6e(i), batt6p(i)] = deal((batt6e(i-1) + bp), bp);
    elseif -excess(i) >= bp
        [batt2e(i), batt2p(i)] = deal((batt2e(i-1) - bp), -bp);
        [batt4e(i), batt4p(i)] = deal((batt4e(i-1) - bp), -bp);
        [batt6e(i), batt6p(i)] = deal((batt6e(i-1) - bp), -bp);
    elseif excess(i) < bp
        [batt2e(i), batt2p(i)] = deal((batt2e(i-1) + excess(i)), excess(i));
        [batt4e(i), batt4p(i)] = deal((batt4e(i-1) + excess(i)), excess(i));
        [batt6e(i), batt6p(i)] = deal((batt6e(i-1) + excess(i)), excess(i));
    end
    
    % Stay within bounds
    if batt2e(i) > (bp*2*4)
        [batt2e(i), batt2p(i)] = deal((bp*2*4), (batt2p(i) - (batt2e(i)-(bp*2*4))));
    elseif batt2e(i) < 0
        [batt2e(i), batt2p(i)] = deal(0, (batt2p(i) - batt2e(i)));
    end

    if batt4e(i) > (bp*4*4)
        [batt4e(i), batt4p(i)] = deal((bp*4*4), (batt4p(i) - (batt4e(i)-(bp*4*4))));
    elseif batt4e(i) < 0
        [batt4e(i), batt4p(i)] = deal(0, (batt4p(i) - batt4e(i)));
    end

    if batt6e(i) > (bp*6*4)
        [batt6e(i), batt6p(i)] = deal((bp*6*4), (batt6p(i) - (batt6e(i)-(bp*6*4))));
    elseif batt6e(i) < 0
        [batt6e(i), batt6p(i)] = deal(0, (batt6p(i) - batt6e(i)));
    end

    % Summing the energy that goes to the battery
    if batt2p(i) > 0
        batt2pStoredkWh = batt2pStoredkWh + batt2p(i);
    end
    if batt4p(i) > 0
        batt4pStoredkWh = batt4pStoredkWh + batt4p(i);
    end
    if batt6p(i) > 0
        batt6pStoredkWh = batt6pStoredkWh + batt6p(i);
    end
    
end
        hold (app.UIAxes, "on")
        plot(app.UIAxes,app.combinedFirst.time,batt2p,"--",LineWidth=2,Color="#7E2F8E")
        plot(app.UIAxes,app.combinedFirst.time,batt4p,"-.",LineWidth=2,Color=[0.2 0.7 0.7])
        plot(app.UIAxes,app.combinedFirst.time,batt6p,":",LineWidth=2,Color="#77AC30")
        legend(app.UIAxes,"load", "pv", "excess generated","load peak","2hr","4hr","6hr")
        hold (app.UIAxes, "off")
end

%end 


           end
        end

        % Value changed function: BatterykWListBox
        function BatterykWListBoxValueChanged(app, event)
app.BatterySimulationSwitchValueChanged;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            ylabel(app.UIAxes, 'kWh')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @DirectoryLinkTextAreaValueChanged, true);
            app.UIAxes.Visible = 'off';
            app.DirectoryLinkLabel = uilabel(app.UIFigure);
            app.DirectoryLinkLabel.Text = {'Directory'; 'Link'};
            app.DirectoryLinkTextArea = uitextarea(app.UIFigure);
            app.DirectoryLinkTextArea.ValueChangedFcn = createCallbackFcn(app, @DirectoryLinkTextAreaValueChanged, true);
            app.DirectoryLinkTextArea.Value = {'G:\Shared drives\Campus Energy\Staff\Claire\Resources\FilePaths.xlsx'};
            app.CorrectButton = uibutton(app.UIFigure, 'push');
            app.CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectButtonPushed, true);
            app.CorrectButton.Text = 'Correct?';
            app.fyDropDownLabel = uilabel(app.UIFigure);
            app.fyDropDownLabel.Visible = 'off';
            app.fyDropDownLabel.Text = 'fy';
            app.fyDropDown = uidropdown(app.UIFigure);
            app.fyDropDown.Items = {'2021-2022', '2022-2023', '2023-2024'};
            app.fyDropDown.ItemsData = [2021 2022 2023];
            app.fyDropDown.ValueChangedFcn = createCallbackFcn(app, @fyDropDownValueChanged, true);
            app.fyDropDown.Visible = 'off';
            app.fyDropDown.Value = 2021;
            app.MonthListBoxLabel = uilabel(app.UIFigure);
            app.MonthListBoxLabel.Visible = 'off';
            app.MonthListBoxLabel.Text = 'Month';
            app.MonthListBox = uilistbox(app.UIFigure);
            app.MonthListBox.Items = {'July', 'August', 'September', 'October', 'November', 'December', 'January', 'February', 'March', 'April', 'May', 'June'};
            app.MonthListBox.ItemsData = [7 8 9 10 11 12 1 2 3 4 5 6];
            app.MonthListBox.ValueChangedFcn = createCallbackFcn(app, @MonthListBoxValueChanged, true);
            app.MonthListBox.Visible = 'off';
            app.MonthListBox.Value = 1;
            app.BatterykWListBoxLabel = uilabel(app.UIFigure);
            app.BatterykWListBoxLabel.Visible = 'off';
            app.BatterykWListBoxLabel.Text = 'Battery kW';
            app.BatterykWListBox = uilistbox(app.UIFigure);
            app.BatterykWListBox.Items = {'130', '165', '200'};
            app.BatterykWListBox.ItemsData = [130 165 200];
            app.BatterykWListBox.ValueChangedFcn = createCallbackFcn(app, @BatterykWListBoxValueChanged, true);
            app.BatterykWListBox.Visible = 'off';
            app.BatterykWListBox.Value = 130;
            app.DayofMonthSliderLabel = uilabel(app.UIFigure);
            app.DayofMonthSliderLabel.Visible = 'off';
            app.DayofMonthSliderLabel.Text = 'Day of Month';
            app.DayofMonthSlider = uislider(app.UIFigure);
            app.DayofMonthSlider.Limits = [1 31];
            app.DayofMonthSlider.ValueChangedFcn = createCallbackFcn(app, @DayofMonthSliderValueChanged, true);
            app.DayofMonthSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31];
            app.DayofMonthSlider.Visible = 'off';
            app.DayofMonthSlider.Tooltip = {'pick a day of the month'};
            app.DayofMonthSlider.Value = 1;
            app.BatterySimulationSwitchLabel = uilabel(app.UIFigure);
            app.BatterySimulationSwitchLabel.Visible = 'off';
            app.BatterySimulationSwitchLabel.Text = {'Battery'; 'Simulation'};
            app.BatterySimulationSwitch = uiswitch(app.UIFigure, 'slider');
            app.BatterySimulationSwitch.ItemsData = [0 1];
            app.BatterySimulationSwitch.ValueChangedFcn = createCallbackFcn(app, @BatterySimulationSwitchValueChanged, true);
            app.BatterySimulationSwitch.Visible = 'off';
            app.BatterySimulationSwitch.Value = 0;
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Visible = 'off';
            app.UpdateButton.Text = 'Update';
            app.BuildingDropDown = uidropdown(app.UIFigure);
            app.BuildingDropDown.Items = {'Will Appear When Run is Pushed'};
            app.BuildingDropDown.Visible = 'off';
            app.BuildingDropDown.Value = 'Will Appear When Run is Pushed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end